classdef Mash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        InputvaluesPanel     matlab.ui.container.Panel
        Enter1stValueButton  matlab.ui.control.Button
        Enter2ndValueButton  matlab.ui.control.Button
        Enter3rdValueButton  matlab.ui.control.Button
        PlotButton           matlab.ui.control.Button
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
        ContextMenu2         matlab.ui.container.ContextMenu
        Menu_2               matlab.ui.container.Menu
        Menu2_2              matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Enter1stValueButton, 
        % Enter2ndValueButton, Enter3rdValueButton
        function Enter2ndValueButtonPushed(app, event)
            v=input('Enter your value: ')
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             d= input('Please enter the test value again: ')
            mymap = [ 0,0.2, 1
    0 0 1];
[X,Y] = meshgrid(-5:5);
mesh(app.UIAxes,X,Y) % UIAxes is the name of your axes object in app designer. Change it according to your app
axis(app.UIAxes, 'equal');
  % Equal axis aspect ratio
for n=1:d*0.01:5
    Z = sin(n*X+n*Y);
    s = surf(app.UIAxes,X,Y,Z);
    view(120,40);  % Setting viewing angle
    %shading interp
    xlabel(app.UIAxes,'X axis'),ylabel(app.UIAxes,'Y axis')
    zlabel(app.UIAxes,'Z = sin(X+Y)')
    colormap(app.UIAxes,mymap);
    s.CData = Y;
    zlim(app.UIAxes,[-5 5])
    pause(0.01)
    
   
end
        end

        % Callback function
        function ButtonPushed(app, event)
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mesh Grid Iterative Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InputvaluesPanel = uipanel(app.UIFigure);
            app.InputvaluesPanel.Title = 'Input values ';
            app.Enter1stValueButton = uibutton(app.InputvaluesPanel, 'push');
            app.Enter1stValueButton.ButtonPushedFcn = createCallbackFcn(app, @Enter2ndValueButtonPushed, true);
            app.Enter1stValueButton.Text = 'Enter 1st Value';
            app.Enter2ndValueButton = uibutton(app.InputvaluesPanel, 'push');
            app.Enter2ndValueButton.ButtonPushedFcn = createCallbackFcn(app, @Enter2ndValueButtonPushed, true);
            app.Enter2ndValueButton.Text = 'Enter 2nd Value';
            app.Enter3rdValueButton = uibutton(app.InputvaluesPanel, 'push');
            app.Enter3rdValueButton.ButtonPushedFcn = createCallbackFcn(app, @Enter2ndValueButtonPushed, true);
            app.Enter3rdValueButton.Text = 'Enter 3rd Value';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end